classdef filterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HighpassFilterButton            matlab.ui.control.Button
        BandpassFilterButton            matlab.ui.control.Button
        LowpassFilterButton             matlab.ui.control.Button
        BandpassfrequencyinHzupperlimitEditField  matlab.ui.control.NumericEditField
        BandpassfrequencyinHzupperlimitEditFieldLabel  matlab.ui.control.Label
        PassbandfrequencyinHzEditField  matlab.ui.control.NumericEditField
        PassbandfrequencyinHzLabel      matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LowpassFilterButton
        function LowpassFilterButtonPushed(app, event)
            switch app.ListBox.Value

                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            fpass=app.PassbandfrequencyinHzEditField.Value;
            filtered=lowpass(x,fpass,fs);

            Y=fft(x);
            mag=abs(Y);
            N=length(x);
            df=fs/N;
            f=0:df:fs-df;

            Y2=fft(filtered);
            mag2=abs(Y2);
            N2=length(filtered);
            df2=fs/N2;
            f2=0:df2:fs-df2;
            plot(app.UIAxes, f(1:floor(end/2)), 10*log10(mag(1:floor(end/2))));
            xlabel(app.UIAxes, 'Frequency [Hz]');
            ylabel(app.UIAxes, 'Amplitude [dB]');
            title(app.UIAxes, 'Original signal');

            plot(app.UIAxes2, f2(1:floor(end/2)), 10*log10(mag2(1:floor(end/2))));
            xlabel(app.UIAxes2, 'Frequency [Hz]');
            ylabel(app.UIAxes2, 'Amplitude [dB]');
            title(app.UIAxes2, 'Filtered signal');

                    
        end

        % Button pushed function: HighpassFilterButton
        function HighpassFilterButtonPushed(app, event)
                        switch app.ListBox.Value

                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            fpass=app.PassbandfrequencyinHzEditField.Value;
            filtered=highpass(x,fpass,fs);

            Y=fft(x);
            mag=abs(Y);
            N=length(x);
            df=fs/N;
            f=0:df:fs-df;

            Y2=fft(filtered);
            mag2=abs(Y2);
            N2=length(filtered);
            df2=fs/N2;
            f2=0:df2:fs-df2;
            plot(app.UIAxes, f(1:floor(end/2)), 10*log10(mag(1:floor(end/2))));
            xlabel(app.UIAxes, 'Frequency [Hz]');
            ylabel(app.UIAxes, 'Amplitude [dB]');
            title(app.UIAxes, 'Original signal');

            plot(app.UIAxes2, f2(1:floor(end/2)), 10*log10(mag2(1:floor(end/2))));
            xlabel(app.UIAxes2, 'Frequency [Hz]');
            ylabel(app.UIAxes2, 'Amplitude [dB]');
            title(app.UIAxes2, 'Filtered signal');
        end

        % Button pushed function: BandpassFilterButton
        function BandpassFilterButtonPushed(app, event)
                        switch app.ListBox.Value

                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            fpassL=app.PassbandfrequencyinHzEditField.Value;
            fpassU=app.BandpassfrequencyinHzupperlimitEditField.Value;
            filtered=bandpass(x,[fpassL,fpassU],fs);

            Y=fft(x);
            mag=abs(Y);
            N=length(x);
            df=fs/N;
            f=0:df:fs-df;

            Y2=fft(filtered);
            mag2=abs(Y2);
            N2=length(filtered);
            df2=fs/N2;
            f2=0:df2:fs-df2;
            plot(app.UIAxes, f(1:floor(end/2)), 10*log10(mag(1:floor(end/2))));
            xlabel(app.UIAxes, 'Frequency [Hz]');
            ylabel(app.UIAxes, 'Amplitude [dB]');
            title(app.UIAxes, 'Original signal');

            plot(app.UIAxes2, f2(1:floor(end/2)), 10*log10(mag2(1:floor(end/2))));
            xlabel(app.UIAxes2, 'Frequency [Hz]');
            ylabel(app.UIAxes2, 'Amplitude [dB]');
            title(app.UIAxes2, 'Filtered signal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Motor testbench', 'wood_saw', 'tea pot'};
            app.ListBox.Value = 'Motor testbench';
            app.PassbandfrequencyinHzLabel = uilabel(app.UIFigure);
            app.PassbandfrequencyinHzLabel.Text = ' Passband frequency in Hz          ';
            app.PassbandfrequencyinHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.PassbandfrequencyinHzEditField.Limits = [0 Inf];
            app.PassbandfrequencyinHzEditField.Value = 3000;
            app.BandpassfrequencyinHzupperlimitEditFieldLabel = uilabel(app.UIFigure);
            app.BandpassfrequencyinHzupperlimitEditFieldLabel.Text = 'Bandpass frequency in Hz (upper limit)';
            app.BandpassfrequencyinHzupperlimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandpassfrequencyinHzupperlimitEditField.Limits = [0 Inf];
            app.BandpassfrequencyinHzupperlimitEditField.Value = 4000;
            app.LowpassFilterButton = uibutton(app.UIFigure, 'push');
            app.LowpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LowpassFilterButtonPushed, true);
            app.LowpassFilterButton.Text = 'Lowpass Filter';
            app.BandpassFilterButton = uibutton(app.UIFigure, 'push');
            app.BandpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassFilterButtonPushed, true);
            app.BandpassFilterButton.Text = 'Bandpass Filter';
            app.HighpassFilterButton = uibutton(app.UIFigure, 'push');
            app.HighpassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HighpassFilterButtonPushed, true);
            app.HighpassFilterButton.Text = 'Highpass Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end