classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SignalIntegrationButton   matlab.ui.control.Button
        detrendButton             matlab.ui.control.Button
        addtrendButton            matlab.ui.control.Button
        FilterButton              matlab.ui.control.Button
        SignaltoloadListBox       matlab.ui.control.ListBox
        SignaltoloadListBoxLabel  matlab.ui.control.Label
        PlaysoundButton           matlab.ui.control.Button
        SpectrumPlotButton        matlab.ui.control.Button
        SpectrumPlotdbButton      matlab.ui.control.Button
        TimePlotButton            matlab.ui.control.Button
        SinwaveButton             matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Callback function
        function PlotSignalAF(app, event)

        end

        % Button pushed function: SinwaveButton
        function SinwaveButtonPushed(app, event)
            cla(app.UIAxes); % Clears the axes
            x = linspace(0, 2*pi, 100); % Generate X values
            y = sin(x); % Compute sine wave
            plot(app.UIAxes, x, y); % Plot on the UIAxes
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Sine Wave');
        end

        % Button pushed function: TimePlotButton
        function PlotTime(app, event)
            disp(app.SignaltoloadListBox)

            switch app.SignaltoloadListBox.Value
                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            N=length(x);
            downsampleRate=8;

            N=N/downsampleRate;
            fs=fs/downsampleRate;
            
            x=x(1:downsampleRate:end);
            
            t=(0:N-1)/fs;
            plot(app.UIAxes, t, x);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Time plot');
        end

        % Button pushed function: SpectrumPlotdbButton
        function SpectrumPlotdbButtonPushed(app, event)
            switch app.SignaltoloadListBox.Value
        
                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            N=length(x);
            downsampleRate=8;
            N=N/downsampleRate;
            fs=fs/downsampleRate;
            x=x(1:downsampleRate:end);           
            df=fs/N;
            f=0:df:fs-df;
            f=f';
            Y=fft(x);
            mag=abs(Y);
            phase=angle(Y);

 
            plot(app.UIAxes, f(1:floor(end/2)), 10*log10(mag(1:floor(end/2))));
            xlabel(app.UIAxes, 'Frequency [Hz]');
            ylabel(app.UIAxes, 'Amplitude [dB]');
            title(app.UIAxes, 'Freq plot');

            plot(app.UIAxes2, f, phase/pi);
            xlabel(app.UIAxes2, 'Frequency [Hz]');
            ylabel(app.UIAxes2, 'Phase/pi [rad]');
            title(app.UIAxes2, 'Phase plot');
 

        end

        % Button pushed function: SpectrumPlotButton
        function SpectrumPlotButtonPushed(app, event)
            switch app.SignaltoloadListBox.Value
        
                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            N=length(x);
            downsampleRate=8;
            N=N/downsampleRate;
            fs=fs/downsampleRate;
            x=x(1:downsampleRate:end);           
            df=fs/N;
            f=0:df:fs-df;
            f=f';
            Y=fft(x);
            mag=abs(Y);
            phase=angle(Y);
            plot(app.UIAxes, f(1:floor(end/2)), (mag(1:floor(end/2))));
            xlabel(app.UIAxes, 'Frequency');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Freq plot');

            plot(app.UIAxes2, f, phase/pi);
            xlabel(app.UIAxes2, 'Frequency');
            ylabel(app.UIAxes2, 'Phase/pi');
            title(app.UIAxes2, 'Phase plot');

          
        end

        % Button pushed function: PlaysoundButton
        function PlaysoundButtonPushed(app, event)
            switch app.SignaltoloadListBox.Value
        
                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            sound(x,fs);
        end

        % Callback function
        function FiletolaodEditFieldValueChanged(app, event)

        end

        % Value changed function: SignaltoloadListBox
        function SignaltoloadListBoxValueChanged(app, event)

        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            filterApp
        end

        % Button pushed function: addtrendButton
        function addtrendButtonPushed(app, event)
            switch app.SignaltoloadListBox.Value

                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end

            N=length(x);
            downsampleRate=8;

            N=N/downsampleRate;
            fs=fs/downsampleRate;

            x=x(1:downsampleRate:end);

            t=(0:N-1)/fs;
            plot(app.UIAxes, t, x);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Time plot');

            trend=t'+5;
            plot(app.UIAxes2, t, x+trend);
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');
            title(app.UIAxes2, 'Time plot');
        end

        % Button pushed function: detrendButton
        function detrendButtonPushed(app, event)
                        switch app.SignaltoloadListBox.Value

                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end

            N=length(x);
            downsampleRate=8;

            N=N/downsampleRate;
            fs=fs/downsampleRate;

            x=x(1:downsampleRate:end);

            t=(0:N-1)/fs;
            trend=t'+5;

            plot(app.UIAxes, t, x+trend);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Time plot');

            detrended=detrend(x+trend);
            plot(app.UIAxes2, t, detrended);
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Amplitude');
            title(app.UIAxes2, 'Time plot');
        end

        % Button pushed function: SignalIntegrationButton
        function SignalIntegrationButtonPushed(app, event)
            switch app.SignaltoloadListBox.Value

                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            N=length(x);
            downsampleRate=8;

            N=N/downsampleRate;
            fs=fs/downsampleRate;

            x=x(1:downsampleRate:end);

            t=(0:N-1)/fs;
            y_integrated = cumtrapz(t, x); 

            plot(app.UIAxes,t, x, 'b', 'DisplayName', 'Original Signal');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,t, y_integrated, 'r--', 'DisplayName', 'Time-Domain Integral');
            legend(app.UIAxes);
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Time-Domain Integration');
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'off');


            % Avoid division by zero at ω = 0
            omega(1) = 1e-6; % Prevents division by zero

            % Apply integration in the frequency domain
            Y_int_f = fft(x) ./ (1j * omega);

            % Compute inverse FFT to return to time domain
            y_int_f = ifft(Y_int_f, 'symmetric');

            % Plot results

            plot(app.UIAxes2, t, y_int_f, 'g', 'DisplayName', 'Freq-Domain Integral');
            legend(app.UIAxes2);
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Amplitude');
            title(app.UIAxes2, 'Frequency-Domain Integration');
            grid(app.UIAxes2,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SinwaveButton = uibutton(app.UIFigure, 'push');
            app.SinwaveButton.ButtonPushedFcn = createCallbackFcn(app, @SinwaveButtonPushed, true);
            app.SinwaveButton.Text = 'Sin wave';
            app.TimePlotButton = uibutton(app.UIFigure, 'push');
            app.TimePlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTime, true);
            app.TimePlotButton.Text = 'Time Plot';
            app.SpectrumPlotdbButton = uibutton(app.UIFigure, 'push');
            app.SpectrumPlotdbButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumPlotdbButtonPushed, true);
            app.SpectrumPlotdbButton.Text = 'Spectrum Plot [db]';
            app.SpectrumPlotButton = uibutton(app.UIFigure, 'push');
            app.SpectrumPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumPlotButtonPushed, true);
            app.SpectrumPlotButton.Text = 'Spectrum Plot';
            app.PlaysoundButton = uibutton(app.UIFigure, 'push');
            app.PlaysoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaysoundButtonPushed, true);
            app.PlaysoundButton.Text = 'Play sound';
            app.SignaltoloadListBoxLabel = uilabel(app.UIFigure);
            app.SignaltoloadListBoxLabel.Text = 'Signal to load';
            app.SignaltoloadListBox = uilistbox(app.UIFigure);
            app.SignaltoloadListBox.Items = {'Motor testbench', 'wood_saw', 'tea pot'};
            app.SignaltoloadListBox.ValueChangedFcn = createCallbackFcn(app, @SignaltoloadListBoxValueChanged, true);
            app.SignaltoloadListBox.Value = 'Motor testbench';
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.addtrendButton = uibutton(app.UIFigure, 'push');
            app.addtrendButton.ButtonPushedFcn = createCallbackFcn(app, @addtrendButtonPushed, true);
            app.addtrendButton.Text = 'add trend';
            app.detrendButton = uibutton(app.UIFigure, 'push');
            app.detrendButton.ButtonPushedFcn = createCallbackFcn(app, @detrendButtonPushed, true);
            app.detrendButton.Text = 'detrend';
            app.SignalIntegrationButton = uibutton(app.UIFigure, 'push');
            app.SignalIntegrationButton.ButtonPushedFcn = createCallbackFcn(app, @SignalIntegrationButtonPushed, true);
            app.SignalIntegrationButton.Text = 'Signal Integration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end