classdef test_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        Button                    matlab.ui.control.Button
        SignaltoloadListBox       matlab.ui.control.ListBox
        SignaltoloadListBoxLabel  matlab.ui.control.Label
        PlaysoundButton           matlab.ui.control.Button
        TimePlotButton            matlab.ui.control.Button
        SinwaveButton             matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        SpectrumPlotButton        matlab.ui.control.Button
        SpectrumPlotdbButton      matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function DropDownValueChanged(app, event)
            
        end

        % Callback function
        function PlotSignalAF(app, event)
            
        end

        % Button pushed function: SinwaveButton
        function SinwaveButtonPushed(app, event)
            cla(app.UIAxes); % Clears the axes
            x = linspace(0, 2*pi, 100); % Generate X values
            y = sin(x); % Compute sine wave
            plot(app.UIAxes, x, y); % Plot on the UIAxes
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Sine Wave');
        end

        % Button pushed function: TimePlotButton
        function PlotTime(app, event)
            disp(app.SignaltoloadListBox)

            switch app.SignaltoloadListBox.Value
                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            N=length(x);
            downsampleRate=8;

            N=N/downsampleRate;
            fs=fs/downsampleRate;
            
            x=x(1:downsampleRate:end);
            
            t=(0:N-1)/fs;
            plot(app.UIAxes, t, x);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Time plot');
        end

        % Button pushed function: PlaysoundButton
        function PlaysoundButtonPushed(app, event)
            switch app.SignaltoloadListBox.Value
        
                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            sound(x,fs);
        end

        % Value changed function: SignaltoloadListBox
        function SignaltoloadListBoxValueChanged(app, event)

        end

        % Button pushed function: SpectrumPlotdbButton
        function SpectrumPlotdbButtonPushed(app, event)
            switch app.SignaltoloadListBox.Value
        
                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            N=length(x);
            downsampleRate=8;
            N=N/downsampleRate;
            fs=fs/downsampleRate;
            x=x(1:downsampleRate:end);           
            df=fs/N;
            f=0:df:fs-df;
            f=f';
            Y=fft(x);
            mag=abs(Y);
            phase=angle(Y);

 
            plot(app.UIAxes, f(1:floor(end/2)), 10*log10(mag(1:floor(end/2))));
            xlabel(app.UIAxes, 'Frequency');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Freq plot');

            plot(app.UIAxes2, f, phase/pi);
            xlabel(app.UIAxes2, 'Frequency');
            ylabel(app.UIAxes2, 'Phase/pi');
            title(app.UIAxes2, 'Phase plot');
 

        end

        % Callback function
        function FiletolaodEditFieldValueChanged(app, event)

        end

        % Button pushed function: SpectrumPlotButton
        function SpectrumPlotButtonPushed(app, event)
            switch app.SignaltoloadListBox.Value
        
                case 'Motor testbench'
                    load vibstand.mat
                    fs=avtb_2019_Machine_1_02_Motor_bearing_metadata.SamplingRate;
                    x=avtb_2019_Machine_1_02_Motor_bearing(:,2);
                case 'wood_saw'
                    load signal_1_wood_saw_decaying_pulse.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
                case 'tea pot'
                    load signal_3_tea_pot.mat
                    x=Basic_reading_Machine_1_Vibro_1(:,2);
                    fs = Basic_reading_Machine_1_Vibro_1_metadata.SamplingRate;
            end
            N=length(x);
            downsampleRate=8;
            N=N/downsampleRate;
            fs=fs/downsampleRate;
            x=x(1:downsampleRate:end);           
            df=fs/N;
            f=0:df:fs-df;
            f=f';
            Y=fft(x);
            mag=abs(Y);
            phase=angle(Y);
            plot(app.UIAxes, f(1:floor(end/2)), (mag(1:floor(end/2))));
            xlabel(app.UIAxes, 'Frequency');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Freq plot');

            plot(app.UIAxes2, f, phase/pi);
            xlabel(app.UIAxes2, 'Frequency');
            ylabel(app.UIAxes2, 'Phase/pi');
            title(app.UIAxes2, 'Phase plot');

          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SinwaveButton = uibutton(app.LeftPanel, 'push');
            app.SinwaveButton.ButtonPushedFcn = createCallbackFcn(app, @SinwaveButtonPushed, true);
            app.SinwaveButton.Text = 'Sin wave';
            app.TimePlotButton = uibutton(app.LeftPanel, 'push');
            app.TimePlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTime, true);
            app.TimePlotButton.Text = 'Time Plot';
            app.PlaysoundButton = uibutton(app.LeftPanel, 'push');
            app.PlaysoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaysoundButtonPushed, true);
            app.PlaysoundButton.Text = 'Play sound';
            app.SignaltoloadListBoxLabel = uilabel(app.LeftPanel);
            app.SignaltoloadListBoxLabel.Text = 'Signal to load';
            app.SignaltoloadListBox = uilistbox(app.LeftPanel);
            app.SignaltoloadListBox.Items = {'Motor testbench', 'wood_saw', 'tea pot'};
            app.SignaltoloadListBox.ValueChangedFcn = createCallbackFcn(app, @SignaltoloadListBoxValueChanged, true);
            app.SignaltoloadListBox.Value = 'Motor testbench';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SpectrumPlotdbButton = uibutton(app.RightPanel, 'push');
            app.SpectrumPlotdbButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumPlotdbButtonPushed, true);
            app.SpectrumPlotdbButton.Text = 'Spectrum Plot [db]';
            app.SpectrumPlotButton = uibutton(app.RightPanel, 'push');
            app.SpectrumPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumPlotButtonPushed, true);
            app.SpectrumPlotButton.Text = 'Spectrum Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end