classdef STFT_CWT_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SignalDropDownCWT              matlab.ui.control.DropDown
        STFTSignalLabel_2              matlab.ui.control.Label
        WindowLengthEditField          matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel     matlab.ui.control.Label
        WindowSelectorDropDown         matlab.ui.control.DropDown
        WindowSelectorDropDownLabel    matlab.ui.control.Label
        CWTParametersLabel             matlab.ui.control.Label
        STFTParametersLabel            matlab.ui.control.Label
        RuntheanalysisLabel            matlab.ui.control.Label
        InputsignalLabel               matlab.ui.control.Label
        SignalDropDownSTFT             matlab.ui.control.DropDown
        STFTSignalLabel                matlab.ui.control.Label
        OverlapEditField               matlab.ui.control.NumericEditField
        OverlapEditFieldLabel          matlab.ui.control.Label
        NfftEditField                  matlab.ui.control.NumericEditField
        NfftEditFieldLabel             matlab.ui.control.Label
        AnalyticalWavletDropDown       matlab.ui.control.DropDown
        AnalyticalWavletDropDownLabel  matlab.ui.control.Label
        CWTButton                      matlab.ui.control.Button
        STFTButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes1                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        signal1 % Signal to be transformed
        fs1 % Sampling frequency
        signal2 % Signal to be transformed
        fs2 % Sampling frequency
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STFTButton
        function STFTButtonPushed(app, event)
            % Get user inputs
            windowLength = app.WindowLengthEditField.Value;
            Nfft = app.NfftEditField.Value;
            overlap = app.OverlapEditField.Value;
            windowType = app.WindowSelectorDropDown.Value;
            signalType1 = app.SignalDropDownSTFT.Value;
            % Generate a Hamming window
            switch windowType
                case 'hann'
                    g = hann(windowLength, 'periodic');
                case 'hamming'
                    g = hamming(windowLength, 'periodic');
                case 'kaiser'
                    % Kaiser window requires an additional parameter, beta
                    beta = 5; % Example beta value, you can make this user-defined
                    g = kaiser(windowLength, beta);
                case 'blackman'
                    g = blackman(windowLength, 'periodic');

                otherwise
                    error('Unsupported window type');
            end

            switch signalType1
                case 'chirp'
                    app.fs1 = 1400;
                    t1 = 0:1/app.fs1:2;
                    app.signal1 = chirp(t1, 600, 2, 100);
                case 'vco'
                    app.fs1 = 10e3;
                    t = 0:1/app.fs1:2;
                    app.signal1 = vco(sin(2*pi*t),[0.1 0.4]*app.fs1,app.fs1);
                otherwise
                    error('Unsupported window type');
            end

            % Select the appropriate signal
            % if strcmp(app.SignalDropDownSTFT.Value, 'Signal 1')
            %     app.signal = app.signal1;
            %     app.fs = app.fs1;
            % else
            %     uialert(app.UIFigure, 'STFT can only be applied to Signal 1', 'Error');
            %     return;
            % end
            % Perform STFT using MYstft function
            [S, F, T] = MYstft(app.signal1, app.fs1, g, overlap, Nfft);

            % Display STFT result
            imagesc(app.UIAxes1, T, F, 20*log10(abs(S)));
            axis(app.UIAxes1, 'xy');
            title(app.UIAxes1, 'STFT Result');
            xlabel(app.UIAxes1, 'Time (s)');
            ylabel(app.UIAxes1, 'Frequency (Hz)');
        end

        % Button pushed function: CWTButton
        function CWTButtonPushed(app, event)
            % Get user inputs
            waveletType = app.AnalyticalWavletDropDown.Value;
            % Select the appropriate signal
            signalType2 = app.SignalDropDownCWT.Value;
            % Generate a Hamming window
            

            switch signalType2
                case 'expNoise'
                    app.fs2 = 1000;
                    t2 = 0:1/app.fs2:1;
                    app.signal2 = exp(1i*2*pi*32*t2).*(t2>=0.1 & t2<0.3) + 2*exp(-1i*2*pi*64*t2).*(t2>0.7);
                    wgnNoise = 0.05/sqrt(2)*randn(size(t2)) + 1i*0.05/sqrt(2)*randn(size(t2));
                    app.signal2 = app.signal2 + wgnNoise;
                case '3sin'
                    app.fs2 = 1000;
                    % Time vector
                    t = 0:1/app.fs2:1-1/app.fs2;
                    
                    % Create a signal with multiple frequency components
                    f1 = 50; % Frequency of the first sine wave
                    f2 = 150; % Frequency of the second sine wave
                    f3 = 300; % Frequency of the third sine wave
                    
                    % Sum of sine waves
                    signal = sin(2*pi*f1*t) + sin(2*pi*f2*t) + sin(2*pi*f3*t);
                    
                    % Add some noise
                    app.signal2 = signal + 0.5*randn(size(t));
                otherwise
                    error('Unsupported window type');
            end

            
            % Perform CWT using MYcwt function
            [timeFreqSpectrum, freqBins, coi] = MYcwt(app.signal2, app.fs2, waveletType);

            % % Display CWT result
            % t = linspace(0, length(app.signal2)/app.fs2, size(timeFreqSpectrum, 2));
            % imagesc(app.UIAxes2, t, freqBins, abs(timeFreqSpectrum));
            % axis(app.UIAxes2, 'xy');
            % title(app.UIAxes2, 'CWT Result');
            % xlabel(app.UIAxes2, 'Time (s)');
            % ylabel(app.UIAxes2, 'Frequency (Hz)');
                    % Display CWT result
        t = linspace(0, length(app.signal2)/app.fs2, size(timeFreqSpectrum, 2));
        PlotSpectrum(app.UIAxes2, t, freqBins, timeFreqSpectrum, coi);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'STFT')
            xlabel(app.UIAxes1, 'Time [s]')
            ylabel(app.UIAxes1, 'Frequency [Hz]')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CWT')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Frequency [Hz]')
            zlabel(app.UIAxes2, 'Z')
            app.STFTButton = uibutton(app.UIFigure, 'push');
            app.STFTButton.ButtonPushedFcn = createCallbackFcn(app, @STFTButtonPushed, true);
            app.STFTButton.Text = 'STFTButton';
            app.CWTButton = uibutton(app.UIFigure, 'push');
            app.CWTButton.ButtonPushedFcn = createCallbackFcn(app, @CWTButtonPushed, true);
            app.CWTButton.Text = 'CWTButton';
            app.AnalyticalWavletDropDownLabel = uilabel(app.UIFigure);
            app.AnalyticalWavletDropDownLabel.Text = 'Analytical Wavlet';
            app.AnalyticalWavletDropDown = uidropdown(app.UIFigure);
            app.AnalyticalWavletDropDown.Items = {'morse', 'morlet'};
            app.AnalyticalWavletDropDown.Value = 'morse';
            app.NfftEditFieldLabel = uilabel(app.UIFigure);
            app.NfftEditFieldLabel.Text = 'NfftEditField';
            app.NfftEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapEditFieldLabel.Text = 'OverlapEditField';
            app.OverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.STFTSignalLabel = uilabel(app.UIFigure);
            app.STFTSignalLabel.Text = 'STFT Signal';
            app.SignalDropDownSTFT = uidropdown(app.UIFigure);
            app.SignalDropDownSTFT.Items = {'chirp', 'vco'};
            app.SignalDropDownSTFT.Value = 'chirp';
            app.InputsignalLabel = uilabel(app.UIFigure);
            app.InputsignalLabel.Text = 'Input signal';
            app.RuntheanalysisLabel = uilabel(app.UIFigure);
            app.RuntheanalysisLabel.Text = 'Run the analysis';
            app.STFTParametersLabel = uilabel(app.UIFigure);
            app.STFTParametersLabel.Text = 'STFT Parameters';
            app.CWTParametersLabel = uilabel(app.UIFigure);
            app.CWTParametersLabel.Text = 'CWT Parameters';
            app.WindowSelectorDropDownLabel = uilabel(app.UIFigure);
            app.WindowSelectorDropDownLabel.Text = 'WindowSelector';
            app.WindowSelectorDropDown = uidropdown(app.UIFigure);
            app.WindowSelectorDropDown.Items = {'hann', 'hamming', 'kaiser', 'blackman'};
            app.WindowSelectorDropDown.Value = 'hann';
            app.WindowLengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthEditFieldLabel.Text = 'WindowLength';
            app.WindowLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.STFTSignalLabel_2 = uilabel(app.UIFigure);
            app.STFTSignalLabel_2.Text = 'CWTSignal';
            app.SignalDropDownCWT = uidropdown(app.UIFigure);
            app.SignalDropDownCWT.Items = {'expNoise', '3sin'};
            app.SignalDropDownCWT.Value = 'expNoise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STFT_CWT_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end