classdef gui1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OverlapEditField               matlab.ui.control.NumericEditField
        OverlapEditFieldLabel          matlab.ui.control.Label
        NfftEditField                  matlab.ui.control.NumericEditField
        NfftEditFieldLabel             matlab.ui.control.Label
        AnalyticalWavletDropDown       matlab.ui.control.DropDown
        AnalyticalWavletDropDownLabel  matlab.ui.control.Label
        CWTButton                      matlab.ui.control.Button
        STFTButton                     matlab.ui.control.Button
        WindowLengthEditField          matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel     matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        signal % Signal to be transformed
        fs % Sampling frequency
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STFTButton
        function STFTButtonPushed(app, event)
            % Get user inputs
            windowLength = app.WindowLengthEditField.Value;
            Nfft = app.NfftEditField.Value;
            overlap = app.OverlapEditField.Value;

            % Generate a Hamming window
            window = hamming(windowLength);

            % Perform STFT using MYstft function
            [S, F, T] = MYstft(app.signal, app.fs, window, overlap, Nfft);

            % Display STFT result
            imagesc(app.UIAxes1, T, F, 20*log10(abs(S)));
            axis(app.UIAxes1, 'xy');
            title(app.UIAxes1, 'STFT Result');
            xlabel(app.UIAxes1, 'Time (s)');
            ylabel(app.UIAxes1, 'Frequency (Hz)');

        end

        % Button pushed function: CWTButton
        function CWTButtonPushed(app, event)
            % Get user inputs
            waveletType = app.WaveletTypeDropDown.Value;

            % Perform CWT using MYcwt function
            [timeFreqSpectrum, freqBins] = MYcwt(app.signal, app.fs, waveletType);

            % Display CWT result
            t = linspace(0, length(app.signal)/app.fs, size(timeFreqSpectrum, 2));
            imagesc(app.UIAxes2, t, freqBins, abs(timeFreqSpectrum));
            axis(app.UIAxes2, 'xy');
            title(app.UIAxes2, 'CWT Result');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Frequency (Hz)');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxes1')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Frequency [Hz]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'UIAxes2')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Frequency [Hz]')
            zlabel(app.UIAxes2, 'Z')
            app.WindowLengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthEditFieldLabel.Text = 'WindowLengthEditField';
            app.WindowLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.STFTButton = uibutton(app.UIFigure, 'push');
            app.STFTButton.ButtonPushedFcn = createCallbackFcn(app, @STFTButtonPushed, true);
            app.STFTButton.Text = 'STFTButton';
            app.CWTButton = uibutton(app.UIFigure, 'push');
            app.CWTButton.ButtonPushedFcn = createCallbackFcn(app, @CWTButtonPushed, true);
            app.CWTButton.Text = 'CWTButton';
            app.AnalyticalWavletDropDownLabel = uilabel(app.UIFigure);
            app.AnalyticalWavletDropDownLabel.Text = 'Analytical Wavlet';
            app.AnalyticalWavletDropDown = uidropdown(app.UIFigure);
            app.AnalyticalWavletDropDown.Items = {'Morse', 'Morlet'};
            app.AnalyticalWavletDropDown.Value = 'Morlet';
            app.NfftEditFieldLabel = uilabel(app.UIFigure);
            app.NfftEditFieldLabel.Text = 'NfftEditField';
            app.NfftEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapEditFieldLabel.Text = 'OverlapEditField';
            app.OverlapEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end